classdef CoorCorrWell < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ScanAreaDropDownLabel    matlab.ui.control.Label
        popupmenu1               matlab.ui.control.DropDown
        DefaultButton            matlab.ui.control.Button
        OKButton                 matlab.ui.control.Button
        CancelButton             matlab.ui.control.Button
        Panel1                   matlab.ui.container.Panel
        edit2                    matlab.ui.control.NumericEditField
        edit4                    matlab.ui.control.NumericEditField
        edit6                    matlab.ui.control.NumericEditField
        edit8                    matlab.ui.control.NumericEditField
        XLabel                   matlab.ui.control.Label
        YLabel                   matlab.ui.control.Label
        LeftEditFieldLabel       matlab.ui.control.Label
        edit1                    matlab.ui.control.NumericEditField
        RightEditFieldLabel      matlab.ui.control.Label
        edit3                    matlab.ui.control.NumericEditField
        UpEditFieldLabel         matlab.ui.control.Label
        edit5                    matlab.ui.control.NumericEditField
        DownEditFieldLabel       matlab.ui.control.Label
        edit7                    matlab.ui.control.NumericEditField
        GetButton1               matlab.ui.control.Button
        GetButton2               matlab.ui.control.Button
        GetButton3               matlab.ui.control.Button
        GetButton4               matlab.ui.control.Button
        MoveButton               matlab.ui.control.Button
        Panel2                   matlab.ui.container.Panel
        edit10                   matlab.ui.control.NumericEditField
        edit12                   matlab.ui.control.NumericEditField
        edit14                   matlab.ui.control.NumericEditField
        edit16                   matlab.ui.control.NumericEditField
        XLabel_2                 matlab.ui.control.Label
        YLabel_2                 matlab.ui.control.Label
        LeftEditField_10Label    matlab.ui.control.Label
        edit9                    matlab.ui.control.NumericEditField
        RightEditField_2Label    matlab.ui.control.Label
        edit11                   matlab.ui.control.NumericEditField
        UpEditField_2Label       matlab.ui.control.Label
        edit13                   matlab.ui.control.NumericEditField
        DownEditField_2Label     matlab.ui.control.Label
        edit15                   matlab.ui.control.NumericEditField
        GetButton5               matlab.ui.control.Button
        GetButton6               matlab.ui.control.Button
        GetButton7               matlab.ui.control.Button
        GetButton8               matlab.ui.control.Button
        MoveButton_2             matlab.ui.control.Button
        Panel4                   matlab.ui.container.Panel
        edit26                   matlab.ui.control.NumericEditField
        edit28                   matlab.ui.control.NumericEditField
        edit30                   matlab.ui.control.NumericEditField
        edit32                   matlab.ui.control.NumericEditField
        XLabel_3                 matlab.ui.control.Label
        YLabel_3                 matlab.ui.control.Label
        LeftEditFieldLabel_2     matlab.ui.control.Label
        edit25                   matlab.ui.control.NumericEditField
        RightEditFieldLabel_2    matlab.ui.control.Label
        edit27                   matlab.ui.control.NumericEditField
        UpEditFieldLabel_2       matlab.ui.control.Label
        edit29                   matlab.ui.control.NumericEditField
        DownEditFieldLabel_2     matlab.ui.control.Label
        edit31                   matlab.ui.control.NumericEditField
        GetButton13              matlab.ui.control.Button
        GetButton14              matlab.ui.control.Button
        GetButton15              matlab.ui.control.Button
        GetButton16              matlab.ui.control.Button
        MoveButton_4             matlab.ui.control.Button
        Panel3                   matlab.ui.container.Panel
        edit18                   matlab.ui.control.NumericEditField
        edit20                   matlab.ui.control.NumericEditField
        edit22                   matlab.ui.control.NumericEditField
        edit24                   matlab.ui.control.NumericEditField
        XLabel_4                 matlab.ui.control.Label
        YLabel_4                 matlab.ui.control.Label
        LeftEditField_10Label_2  matlab.ui.control.Label
        edit17                   matlab.ui.control.NumericEditField
        RightEditField_2Label_2  matlab.ui.control.Label
        edit19                   matlab.ui.control.NumericEditField
        UpEditField_2Label_2     matlab.ui.control.Label
        edit21                   matlab.ui.control.NumericEditField
        DownEditField_2Label_2   matlab.ui.control.Label
        edit23                   matlab.ui.control.NumericEditField
        GetButton9               matlab.ui.control.Button
        GetButton10              matlab.ui.control.Button
        GetButton11              matlab.ui.control.Button
        GetButton12              matlab.ui.control.Button
        MoveButton_3             matlab.ui.control.Button
    end

    
    properties (Access = public)
        MainApp; % Main window
    end
    
    properties (Access = private)
        progDlg; % Description
    end
    
    methods (Access = private)
        
        function editCallback(~, event)
            
%             global step_per_um_X step_per_um_Y coorBackup max_step_X max_step_Y
            global step_per_um_X step_per_um_Y max_step_X max_step_Y

            tmpTag = get(event.Source, 'Tag');
            tmpVal = get(event.Source, 'Value');
            tmpNum = str2double(tmpTag(5:end));
            
%             if isnan(tmpVal)
%                 
%                set(event.Source, 'Value', coorBackup(tmpNum))
%                return;
%                 
%             end
            
            if mod(tmpNum, 2) == 1
                
%                 if coorBackup(tmpNum) < 0
                if tmpVal < 0
                    
                    set(event.Source, 'Value', 0);
                    
%                 elseif coorBackup(tmpNum) > (max_step_X/step_per_um_X)
              elseif tmpVal > (max_step_X/step_per_um_X)
                    
                    set(event.Source, 'Value', floor(max_step_X/step_per_um_X))
                    
                end
                
            else
                
%                 if coorBackup(tmpNum) < 0
                if tmpVal < 0
                        
                    set(event.Source, 'Value', 0)
            
%                 elseif coorBackup(tmpNum) > (max_step_Y/step_per_um_Y)
                elseif tmpVal > (max_step_Y/step_per_um_Y)
            
                    set(event.Source, 'Value', floor(max_step_Y/step_per_um_Y))
            
                end
                
            end
            
%             coorBackup(tmpNum) = tmpVal;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            global CoorCorrFile coorBackup 
            
            app.MainApp = MainApp;

            if exist(CoorCorrFile, 'file') == 2
                
                load(CoorCorrFile)
                
                j = 1;
                
%                 for i = 1:16
                for i = 1:32
            
                    editStr = sprintf('edit%d', i);
            
                    if mod(i, 2) == 1
            
                        set(app.(editStr), 'Value', coorVal(j, 1))
            
                    else
            
                        set(app.(editStr), 'Value', coorVal(j, 2));
                        j = j+1;
            
                    end
            
                end
                
                
                try
                    
                    numVal = noFrame;
                    set(app.popupmenu1, 'Value', app.popupmenu1.Items{numVal});
                    
                catch
                    
                    DefaultButtonPushed(app)
                    
                end
                
            else
                
                DefaultButtonPushed(app)
            
            end
            
            
%             for i = 1:16
            for i = 1:32
            
                editStr = sprintf('edit%d', i);
%                 coorBackup(i) = str2double(get(app.(editStr), 'Value'));
                coorBackup(i) = get(app.(editStr), 'Value');
                
            end
            
            scrPos = get(0, 'ScreenSize');
            
            tmpPosX = MainApp.figure1.Position(1) - app.UIFigure.Position(3) - 10;
            tmpPosY = MainApp.figure1.Position(2) - app.UIFigure.Position(4) + MainApp.figure1.Position(4);
            
            if tmpPosX > 0 && tmpPosY > 0
            
                app.UIFigure.Position(1) = tmpPosX;
                app.UIFigure.Position(2) = tmpPosY;
                
            else
                
                app.UIFigure.Position(1) = 0;
                app.UIFigure.Position(2) = scrPos(4) - app.UIFigure.Position(4) - 50;
                
            end
            
            set(app.MainApp.popupmenu_chip, 'Value', app.MainApp.popupmenu_chip.Items{2}); % Well plate on Main Window
            PopupChipCallback(app.MainApp)
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            global CoorCorrFile lensMag lensMagDefault FluorModeFile...
                im_W_um_default noFrame
            
            app.progDlg = uiprogressdlg(app.UIFigure,...
                'Message', 'Applying current settings...',...
                'Indeterminate', 'on');
            
%             coorVal = zeros(8, 2);
            coorVal = zeros(16, 2);
            
            j = 1;
%             for i=1:16
            for i = 1 : size(coorVal, 1)*size(coorVal, 2)
                
                editStr = sprintf('edit%d', i);
                
                if mod(i, 2) == 1
                    
                    coorVal(j, 1) = get(app.(editStr), 'Value');    
                    
                else
                    
                    coorVal(j, 2) = get(app.(editStr), 'Value');    
                    j = j+1;
                    
                end
            
            end
            
            save(CoorCorrFile, 'coorVal')
            
            wellCent1    = [0 0];
            wellCent8    = [0 0];
            wellCent89   = [0 0];
            wellCent96   = [0 0];
            well2wellHor = 0;
            well2wellVer = 0;
%             well2well_X = 0;
%             well2well_Y = 0;
            
            for i=1:4
                    
                wellCent1  = wellCent1  + [coorVal(i, 1), coorVal(i, 2)];
                wellCent8  = wellCent8  + [coorVal(i+4, 1), coorVal(i+4, 2)];
                wellCent89 = wellCent89 + [coorVal(i+8, 1), coorVal(i+8, 2)];
                wellCent96 = wellCent96 + [coorVal(i+12, 1), coorVal(i+12, 2)];
%                 wellCent96 = wellCent96 + [coorVal(i+4, 1), coorVal(i+4, 2)];
                
            end
            
            wellCent1  = wellCent1 / 4;
            wellCent8  = wellCent8 / 4;
            wellCent89 = wellCent89 / 4;
            wellCent96 = wellCent96 / 4;
            
%             wellRadius = mean(abs([coorVal(2, 1) - coorVal(1, 1), coorVal(3, 2)...
%                 - coorVal(4, 2), coorVal(6, 1) - coorVal(5, 1), coorVal(8, 2) - coorVal(7, 2)]));
            
            well_center_X = wellCent1(1, 1);
            well_center_Y = wellCent1(1, 2);
            
%             tmpStr = get(app.popupmenu1, 'String');
            tmpVal = get(app.popupmenu1, 'Value');
            noFrame = str2double(tmpVal(1));
            
            if noFrame == 1
                
                rect_width_half = 0;
                
            else
            
                rect_width_half = ((noFrame - 1) / 2 + 0.01) * im_W_um_default;
                
            end
            
%             for i=1:4
%             
%                 well2well_X = well2well_X + (coorVal(i+4, 1) - coorVal(i, 1));
%                 well2well_Y = well2well_Y + (coorVal(i+4, 2) - coorVal(i, 2));
%             
%             end
            
%             well2well_X = well2well_X / 4 / 7;
%             well2well_Y = well2well_Y / 4 / 11;

            well2wellHor = (wellCent8 - wellCent1 + wellCent96 - wellCent89) / 2 / 7;
            well2wellVer = (wellCent89 - wellCent1 + wellCent96 - wellCent8) / 2 / 11;
            
            save(CoorCorrFile, 'well_center_X', '-append');
            save(CoorCorrFile, 'well_center_Y', '-append');
            save(CoorCorrFile, 'rect_width_half', '-append');
%             save(CoorCorrFile, 'well2well_X', '-append');
%             save(CoorCorrFile, 'well2well_Y', '-append');
            save(CoorCorrFile, 'well2wellHor', '-append');
            save(CoorCorrFile, 'well2wellVer', '-append');            
            save(CoorCorrFile, 'noFrame', '-append');
            
            for j = 1:96
            
                eval(sprintf('global C1_Chamb%d_X_um C1_Chamb%d_Y_um;', j, j));
            
            end
            
            
            if exist(FluorModeFile, 'file') == 2
                
                load(FluorModeFile)
                
            else
                
                lensMag = lensMagDefault;
                
            end
            
            rect_width_half_mag = rect_width_half * lensMagDefault/lensMag;
            
            C1_Chamb1_X_um = [well_center_X - rect_width_half_mag, well_center_X + rect_width_half_mag];
            C1_Chamb1_Y_um = [well_center_Y - rect_width_half_mag, well_center_Y + rect_width_half_mag];
            
            for i = 2:96
                
                if mod(i-1, 8) == 0
        
                    eval(sprintf('C1_Chamb%d_X_um = C1_Chamb%d_X_um + well2wellVer(1, 1);', i, i-8));
                    eval(sprintf('C1_Chamb%d_Y_um = C1_Chamb%d_Y_um + well2wellVer(1, 2);', i, i-8));
            
                else
            
                    eval(sprintf('C1_Chamb%d_X_um = C1_Chamb%d_X_um + well2wellHor(1, 1);', i, i-1));
                    eval(sprintf('C1_Chamb%d_Y_um = C1_Chamb%d_Y_um + well2wellHor(1, 2);', i, i-1));

                end
                
            end
            
%             for i = 1:12
%                 
%                 for ii = 1:8
%                 
%                     eval(sprintf('C1_Chamb%d_X_um = C1_Chamb1_X_um + well2well_X*(ii-1);', (i-1)*8 + ii));
%                     eval(sprintf('C1_Chamb%d_Y_um = C1_Chamb1_Y_um + well2well_Y*(i-1);', (i-1)*8 + ii));
%                     
%                 end
%                 
%             end
            
            set(app.MainApp.popupmenu_chip, 'Value', app.MainApp.popupmenu_chip.Items{1}); % Well plate on Main Window
            PopupChipCallback(app.MainApp)
            
            togglebutton_Chamb_Act(app.MainApp, 'togglebutton_C1_Chamb', 1);
            
            delete(app.progDlg)
            
            delete(app.UIFigure)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app.UIFigure)
        end

        % Button pushed function: DefaultButton
        function DefaultButtonPushed(app, event)
            global defaultCoor
            
%             for i = 1:16
%             
%                 editStr = sprintf('edit%d', i);    
%                 set(app.(editStr), 'Value', defaultCoor(i))                
%                 
%             end
            
            j = 1;
            for i = 1:32
            
                editStr = sprintf('edit%d', i);
        
                if mod(i, 2) == 1
        
                    set(app.(editStr), 'Value', defaultCoor(j, 1))
        
                else
        
                    set(app.(editStr), 'Value', defaultCoor(j, 2));
                    j = j+1;
        
                end
        
            end
            
            set(app.popupmenu1, 'Value', app.popupmenu1.Items{3});
        end

        % Value changed function: edit1, edit10, edit11, edit12, 
        % edit13, edit14, edit15, edit16, edit17, edit18, edit19, 
        % edit2, edit20, edit21, edit22, edit23, edit24, edit25, 
        % edit26, edit27, edit28, edit29, edit3, edit30, edit31, 
        % edit32, edit4, edit5, edit6, edit7, edit8, edit9
        function edit1ValueChanged(app, event)
%             app.editCallback(app, event)
            app.editCallback(event)
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            
            if strcmp(key, 'return')
                
                OKButtonPushed(app, event)
                
            elseif strcmp(key, 'escape')
                
                delete(app)
                
            end
        end

        % Button pushed function: GetButton1, GetButton10, 
        % GetButton11, GetButton12, GetButton13, GetButton14, 
        % GetButton15, GetButton16, GetButton2, GetButton3, 
        % GetButton4, GetButton5, GetButton6, GetButton7, 
        % GetButton8, GetButton9
        function GetButton1Pushed(app, event)
            btnTagNo = str2double(event.Source.Tag);
            editStrX = sprintf('edit%d', btnTagNo * 2 - 1);
            editStrY = sprintf('edit%d', btnTagNo * 2 );
            
            app.(editStrX).Value = app.MainApp.edit_X.Value;
            app.(editStrY).Value = app.MainApp.edit_Y.Value;
        end

        % Button pushed function: MoveButton, MoveButton_2, 
        % MoveButton_3, MoveButton_4
        function MoveButtonPushed(app, event)
            global X_abs_um Y_abs_um Reset_flag
            
            progDlg = uiprogressdlg(app.UIFigure,...
                'Message', 'Moving...', 'Indeterminate', 'on');
            
            btnTagNo = str2double(event.Source.Tag);
            editNo = (btnTagNo-1) * 8 + 1;
            
            tgtX = 0;
            tgtY = 0;
            
            for i = 0:3
            
                editStrX = sprintf('edit%d', editNo + i*2);
                editStrY = sprintf('edit%d', editNo + i*2 + 1);
                tgtX = tgtX + app.(editStrX).Value;
                tgtY = tgtY + app.(editStrY).Value;
                
            end
            
            tgtX = tgtX/4;
            tgtY = tgtY/4;
            
            distCoorX = abs(X_abs_um - tgtX);
            distCoorY = abs(Y_abs_um - tgtY);
            
            if X_abs_um > tgtX

                Stage_Control(app.MainApp, 'X', 'F', distCoorX);
    
            elseif X_abs_um < tgtX
    
                Stage_Control(app.MainApp, 'X', 'B', distCoorX);
    
            end
            
            if Y_abs_um > tgtY

                Stage_Control(app.MainApp, 'Y', 'F', distCoorY);
    
            elseif Y_abs_um < tgtY
    
                Stage_Control(app.MainApp, 'Y', 'B', distCoorY);
    
            end
            
            app.Panel1.BackgroundColor = [0.94,0.94,0.94];
            app.Panel2.BackgroundColor = [0.94,0.94,0.94];
            app.Panel3.BackgroundColor = [0.94,0.94,0.94];
            app.Panel4.BackgroundColor = [0.94,0.94,0.94];
            
            panStr = sprintf('Panel%d', btnTagNo);
            app.(panStr).BackgroundColor = [0.65,0.65,0.65];
            
            % Reset After Move
            progDlgReset = uiprogressdlg(app.MainApp.figure1,...
                'Message', 'Reset X, Y Stage', 'Indeterminate', 'on');
            
            Reset_flag = 1;
            prevX = X_abs_um;
            prevY = Y_abs_um;
            
%             ResetXY(app.MainApp)            
            ResetStage(app.MainApp, [1 1 0])            
            Stage_Control(app.MainApp, 'X', 'B', prevX)
            Stage_Control(app.MainApp, 'Y', 'B', prevY)
            Reset_flag = 0;
            delete(progDlgReset)
            % Reset After Move End
            
            delete(progDlg)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Carlibration';
            app.UIFigure.Icon = 'Max_icon.png';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.ScanAreaDropDownLabel = uilabel(app.UIFigure);
            app.ScanAreaDropDownLabel.Text = 'Scan Area';
            app.popupmenu1 = uidropdown(app.UIFigure);
            app.popupmenu1.Items = {'1x1', '2x2', '3x3', '4x4', '5x5', '6x6', '7x7', '8x8', '9x9'};
            app.popupmenu1.Value = '1x1';
            app.DefaultButton = uibutton(app.UIFigure, 'push');
            app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonPushed, true);
            app.DefaultButton.Text = 'Default';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.Panel1 = uipanel(app.UIFigure);
            app.Panel1.Title = 'A-12 (well 1)';
            app.edit2 = uieditfield(app.Panel1, 'numeric');
            app.edit2.ValueDisplayFormat = '%.0f';
            app.edit2.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit2.Tag = 'edit2';
            app.edit4 = uieditfield(app.Panel1, 'numeric');
            app.edit4.ValueDisplayFormat = '%.0f';
            app.edit4.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit4.Tag = 'edit4';
            app.edit6 = uieditfield(app.Panel1, 'numeric');
            app.edit6.ValueDisplayFormat = '%.0f';
            app.edit6.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit6.Tag = 'edit6';
            app.edit8 = uieditfield(app.Panel1, 'numeric');
            app.edit8.ValueDisplayFormat = '%.0f';
            app.edit8.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit8.Tag = 'edit8';
            app.XLabel = uilabel(app.Panel1);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.Panel1);
            app.YLabel.Text = 'Y';
            app.LeftEditFieldLabel = uilabel(app.Panel1);
            app.LeftEditFieldLabel.Text = 'Left';
            app.edit1 = uieditfield(app.Panel1, 'numeric');
            app.edit1.ValueDisplayFormat = '%.0f';
            app.edit1.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit1.Tag = 'edit1';
            app.RightEditFieldLabel = uilabel(app.Panel1);
            app.RightEditFieldLabel.Text = 'Right';
            app.edit3 = uieditfield(app.Panel1, 'numeric');
            app.edit3.ValueDisplayFormat = '%.0f';
            app.edit3.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit3.Tag = 'edit3';
            app.UpEditFieldLabel = uilabel(app.Panel1);
            app.UpEditFieldLabel.Text = 'Up';
            app.edit5 = uieditfield(app.Panel1, 'numeric');
            app.edit5.ValueDisplayFormat = '%.0f';
            app.edit5.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit5.Tag = 'edit5';
            app.DownEditFieldLabel = uilabel(app.Panel1);
            app.DownEditFieldLabel.Text = 'Down';
            app.edit7 = uieditfield(app.Panel1, 'numeric');
            app.edit7.ValueDisplayFormat = '%.0f';
            app.edit7.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit7.Tag = 'edit7';
            app.GetButton1 = uibutton(app.Panel1, 'push');
            app.GetButton1.ButtonPushedFcn = createCallbackFcn(app, @GetButton1Pushed, true);
            app.GetButton1.Tag = '1';
            app.GetButton1.Text = 'Get';
            app.GetButton2 = uibutton(app.Panel1, 'push');
            app.GetButton2.ButtonPushedFcn = createCallbackFcn(app, @GetButton1Pushed, true);
            app.GetButton2.Tag = '2';
            app.GetButton2.Text = 'Get';
            app.GetButton3 = uibutton(app.Panel1, 'push');
            app.GetButton3.ButtonPushedFcn = createCallbackFcn(app, @GetButton1Pushed, true);
            app.GetButton3.Tag = '3';
            app.GetButton3.Text = 'Get';
            app.GetButton4 = uibutton(app.Panel1, 'push');
            app.GetButton4.ButtonPushedFcn = createCallbackFcn(app, @GetButton1Pushed, true);
            app.GetButton4.Tag = '4';
            app.GetButton4.Text = 'Get';
            app.MoveButton = uibutton(app.Panel1, 'push');
            app.MoveButton.ButtonPushedFcn = createCallbackFcn(app, @MoveButtonPushed, true);
            app.MoveButton.Tag = '1';
            app.MoveButton.Text = 'Move';
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'H-12 (well 8)';
            app.edit10 = uieditfield(app.Panel2, 'numeric');
            app.edit10.ValueDisplayFormat = '%.0f';
            app.edit10.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit10.Tag = 'edit10';
            app.edit12 = uieditfield(app.Panel2, 'numeric');
            app.edit12.ValueDisplayFormat = '%.0f';
            app.edit12.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit12.Tag = 'edit12';
            app.edit14 = uieditfield(app.Panel2, 'numeric');
            app.edit14.ValueDisplayFormat = '%.0f';
            app.edit14.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit14.Tag = 'edit14';
            app.edit16 = uieditfield(app.Panel2, 'numeric');
            app.edit16.ValueDisplayFormat = '%.0f';
            app.edit16.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit16.Tag = 'edit16';
            app.XLabel_2 = uilabel(app.Panel2);
            app.XLabel_2.Text = 'X';
            app.YLabel_2 = uilabel(app.Panel2);
            app.YLabel_2.Text = 'Y';
            app.LeftEditField_10Label = uilabel(app.Panel2);
            app.LeftEditField_10Label.Text = 'Left';
            app.edit9 = uieditfield(app.Panel2, 'numeric');
            app.edit9.ValueDisplayFormat = '%.0f';
            app.edit9.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit9.Tag = 'edit9';
            app.RightEditField_2Label = uilabel(app.Panel2);
            app.RightEditField_2Label.Text = 'Right';
            app.edit11 = uieditfield(app.Panel2, 'numeric');
            app.edit11.ValueDisplayFormat = '%.0f';
            app.edit11.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit11.Tag = 'edit11';
            app.UpEditField_2Label = uilabel(app.Panel2);
            app.UpEditField_2Label.Text = 'Up';
            app.edit13 = uieditfield(app.Panel2, 'numeric');
            app.edit13.ValueDisplayFormat = '%.0f';
            app.edit13.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit13.Tag = 'edit13';
            app.DownEditField_2Label = uilabel(app.Panel2);
            app.DownEditField_2Label.Text = 'Down';
            app.edit15 = uieditfield(app.Panel2, 'numeric');
            app.edit15.ValueDisplayFormat = '%.0f';
            app.edit15.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit15.Tag = 'edit15';
            app.GetButton5 = uibutton(app.Panel2, 'push');
            app.GetButton5.ButtonPushedFcn = createCallbackFcn(app, @GetButton1Pushed, true);
            app.GetButton5.Tag = '5';
            app.GetButton5.Text = 'Get';
            app.GetButton6 = uibutton(app.Panel2, 'push');
            app.GetButton6.ButtonPushedFcn = createCallbackFcn(app, @GetButton1Pushed, true);
            app.GetButton6.Tag = '6';
            app.GetButton6.Text = 'Get';
            app.GetButton7 = uibutton(app.Panel2, 'push');
            app.GetButton7.ButtonPushedFcn = createCallbackFcn(app, @GetButton1Pushed, true);
            app.GetButton7.Tag = '7';
            app.GetButton7.Text = 'Get';
            app.GetButton8 = uibutton(app.Panel2, 'push');
            app.GetButton8.ButtonPushedFcn = createCallbackFcn(app, @GetButton1Pushed, true);
            app.GetButton8.Tag = '8';
            app.GetButton8.Text = 'Get';
            app.MoveButton_2 = uibutton(app.Panel2, 'push');
            app.MoveButton_2.ButtonPushedFcn = createCallbackFcn(app, @MoveButtonPushed, true);
            app.MoveButton_2.Tag = '2';
            app.MoveButton_2.Text = 'Move';
            app.Panel4 = uipanel(app.UIFigure);
            app.Panel4.Title = 'H-1 (well 96)';
            app.edit26 = uieditfield(app.Panel4, 'numeric');
            app.edit26.ValueDisplayFormat = '%.0f';
            app.edit26.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit26.Tag = 'edit2';
            app.edit28 = uieditfield(app.Panel4, 'numeric');
            app.edit28.ValueDisplayFormat = '%.0f';
            app.edit28.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit28.Tag = 'edit4';
            app.edit30 = uieditfield(app.Panel4, 'numeric');
            app.edit30.ValueDisplayFormat = '%.0f';
            app.edit30.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit30.Tag = 'edit6';
            app.edit32 = uieditfield(app.Panel4, 'numeric');
            app.edit32.ValueDisplayFormat = '%.0f';
            app.edit32.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit32.Tag = 'edit8';
            app.XLabel_3 = uilabel(app.Panel4);
            app.XLabel_3.Text = 'X';
            app.YLabel_3 = uilabel(app.Panel4);
            app.YLabel_3.Text = 'Y';
            app.LeftEditFieldLabel_2 = uilabel(app.Panel4);
            app.LeftEditFieldLabel_2.Text = 'Left';
            app.edit25 = uieditfield(app.Panel4, 'numeric');
            app.edit25.ValueDisplayFormat = '%.0f';
            app.edit25.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit25.Tag = 'edit1';
            app.RightEditFieldLabel_2 = uilabel(app.Panel4);
            app.RightEditFieldLabel_2.Text = 'Right';
            app.edit27 = uieditfield(app.Panel4, 'numeric');
            app.edit27.ValueDisplayFormat = '%.0f';
            app.edit27.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit27.Tag = 'edit3';
            app.UpEditFieldLabel_2 = uilabel(app.Panel4);
            app.UpEditFieldLabel_2.Text = 'Up';
            app.edit29 = uieditfield(app.Panel4, 'numeric');
            app.edit29.ValueDisplayFormat = '%.0f';
            app.edit29.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit29.Tag = 'edit5';
            app.DownEditFieldLabel_2 = uilabel(app.Panel4);
            app.DownEditFieldLabel_2.Text = 'Down';
            app.edit31 = uieditfield(app.Panel4, 'numeric');
            app.edit31.ValueDisplayFormat = '%.0f';
            app.edit31.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit31.Tag = 'edit7';
            app.GetButton13 = uibutton(app.Panel4, 'push');
            app.GetButton13.ButtonPushedFcn = createCallbackFcn(app, @GetButton1Pushed, true);
            app.GetButton13.Tag = '13';
            app.GetButton13.Text = 'Get';
            app.GetButton14 = uibutton(app.Panel4, 'push');
            app.GetButton14.ButtonPushedFcn = createCallbackFcn(app, @GetButton1Pushed, true);
            app.GetButton14.Tag = '14';
            app.GetButton14.Text = 'Get';
            app.GetButton15 = uibutton(app.Panel4, 'push');
            app.GetButton15.ButtonPushedFcn = createCallbackFcn(app, @GetButton1Pushed, true);
            app.GetButton15.Tag = '15';
            app.GetButton15.Text = 'Get';
            app.GetButton16 = uibutton(app.Panel4, 'push');
            app.GetButton16.ButtonPushedFcn = createCallbackFcn(app, @GetButton1Pushed, true);
            app.GetButton16.Tag = '16';
            app.GetButton16.Text = 'Get';
            app.MoveButton_4 = uibutton(app.Panel4, 'push');
            app.MoveButton_4.ButtonPushedFcn = createCallbackFcn(app, @MoveButtonPushed, true);
            app.MoveButton_4.Tag = '4';
            app.MoveButton_4.Text = 'Move';
            app.Panel3 = uipanel(app.UIFigure);
            app.Panel3.Title = 'A-1 (well 89)';
            app.edit18 = uieditfield(app.Panel3, 'numeric');
            app.edit18.ValueDisplayFormat = '%.0f';
            app.edit18.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit18.Tag = 'edit10';
            app.edit20 = uieditfield(app.Panel3, 'numeric');
            app.edit20.ValueDisplayFormat = '%.0f';
            app.edit20.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit20.Tag = 'edit12';
            app.edit22 = uieditfield(app.Panel3, 'numeric');
            app.edit22.ValueDisplayFormat = '%.0f';
            app.edit22.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit22.Tag = 'edit14';
            app.edit24 = uieditfield(app.Panel3, 'numeric');
            app.edit24.ValueDisplayFormat = '%.0f';
            app.edit24.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit24.Tag = 'edit16';
            app.XLabel_4 = uilabel(app.Panel3);
            app.XLabel_4.Text = 'X';
            app.YLabel_4 = uilabel(app.Panel3);
            app.YLabel_4.Text = 'Y';
            app.LeftEditField_10Label_2 = uilabel(app.Panel3);
            app.LeftEditField_10Label_2.Text = 'Left';
            app.edit17 = uieditfield(app.Panel3, 'numeric');
            app.edit17.ValueDisplayFormat = '%.0f';
            app.edit17.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit17.Tag = 'edit9';
            app.RightEditField_2Label_2 = uilabel(app.Panel3);
            app.RightEditField_2Label_2.Text = 'Right';
            app.edit19 = uieditfield(app.Panel3, 'numeric');
            app.edit19.ValueDisplayFormat = '%.0f';
            app.edit19.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit19.Tag = 'edit11';
            app.UpEditField_2Label_2 = uilabel(app.Panel3);
            app.UpEditField_2Label_2.Text = 'Up';
            app.edit21 = uieditfield(app.Panel3, 'numeric');
            app.edit21.ValueDisplayFormat = '%.0f';
            app.edit21.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit21.Tag = 'edit13';
            app.DownEditField_2Label_2 = uilabel(app.Panel3);
            app.DownEditField_2Label_2.Text = 'Down';
            app.edit23 = uieditfield(app.Panel3, 'numeric');
            app.edit23.ValueDisplayFormat = '%.0f';
            app.edit23.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit23.Tag = 'edit15';
            app.GetButton9 = uibutton(app.Panel3, 'push');
            app.GetButton9.ButtonPushedFcn = createCallbackFcn(app, @GetButton1Pushed, true);
            app.GetButton9.Tag = '9';
            app.GetButton9.Text = 'Get';
            app.GetButton10 = uibutton(app.Panel3, 'push');
            app.GetButton10.ButtonPushedFcn = createCallbackFcn(app, @GetButton1Pushed, true);
            app.GetButton10.Tag = '10';
            app.GetButton10.Text = 'Get';
            app.GetButton11 = uibutton(app.Panel3, 'push');
            app.GetButton11.ButtonPushedFcn = createCallbackFcn(app, @GetButton1Pushed, true);
            app.GetButton11.Tag = '11';
            app.GetButton11.Text = 'Get';
            app.GetButton12 = uibutton(app.Panel3, 'push');
            app.GetButton12.ButtonPushedFcn = createCallbackFcn(app, @GetButton1Pushed, true);
            app.GetButton12.Tag = '12';
            app.GetButton12.Text = 'Get';
            app.MoveButton_3 = uibutton(app.Panel3, 'push');
            app.MoveButton_3.ButtonPushedFcn = createCallbackFcn(app, @MoveButtonPushed, true);
            app.MoveButton_3.Tag = '3';
            app.MoveButton_3.Text = 'Move';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CoorCorrWell(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end