classdef FittingSet < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        KtDropDown              matlab.ui.control.DropDown
        KtDropDownLabel         matlab.ui.control.Label
        DriftDropDown           matlab.ui.control.DropDown
        DriftDropDownLabel      matlab.ui.control.Label
        BIDropDown              matlab.ui.control.DropDown
        BIDropDownLabel         matlab.ui.control.Label
        DriftCheckBox           matlab.ui.control.CheckBox
        BulkyIndexBICheckBox    matlab.ui.control.CheckBox
        MassTransferktCheckBox  matlab.ui.control.CheckBox
        CancelButton            matlab.ui.control.Button
        DefaultSetButton        matlab.ui.control.Button
        StartFittingButton      matlab.ui.control.Button
        ScopePanel              matlab.ui.container.Panel
        KoffDropDown            matlab.ui.control.DropDown
        KoffLabel               matlab.ui.control.Label
        KonDropDown             matlab.ui.control.DropDown
        KonLabel                matlab.ui.control.Label
        RmaxDropDown            matlab.ui.control.DropDown
        RmaxLabel               matlab.ui.control.Label
        TabGroup                matlab.ui.container.TabGroup
        GlobalTab               matlab.ui.container.Tab
        GlobalTable             matlab.ui.control.Table
        LocalTab                matlab.ui.container.Tab
        LocalTable              matlab.ui.control.Table
        ConstantTab             matlab.ui.container.Tab
        ConstantTable           matlab.ui.control.Table
    end

    
    properties (Access = private)
   
    end
    
    properties (Access = public)
         MainApp; % Main Application.
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.MainApp = MainApp;          
            DefaultSetButtonPushed(app, [])
        end

        % Button pushed function: DefaultSetButton
        function DefaultSetButtonPushed(app, event)
            app.RmaxDropDown.Value = 'Global';
            app.KonDropDown.Value = 'Global';
            app.KoffDropDown.Value = 'Global';
%             app.BIDropDown.Value = 'Local';
            app.BIDropDown.Value = 'Constant';
            
            RenewTable(app)
        end

        % Value changed function: RmaxDropDown
        function RmaxDropDownValueChanged(app, event)
            RenewTable(app)            
        end

        % Value changed function: KonDropDown
        function KonDropDownValueChanged(app, event)
            RenewTable(app) 
        end

        % Value changed function: KoffDropDown
        function KoffDropDownValueChanged(app, event)
            RenewTable(app) 
        end

        % Value changed function: BIDropDown
        function BIDropDownValueChanged(app, event)
            RenewTable(app)             
        end

        % Button pushed function: StartFittingButton
        function StartFittingButtonPushed(app, event)
            global rawData
            
            numCon = str2double(app.MainApp.UITable.Data.Var2);
            desName = app.MainApp.UITable.Data.Var1;
            
            if isnan(numCon)
                
                uialert(app.UIFigure, 'Invalid Concentration.', '')
                return;
                
            end
            
%             resultData = struct;
%             app.ResultTable.Data = [];
            
            rawData = struct;
            fitProp = struct;
                       
            for i = 1:app.MainApp.dataNo
                            
                assoX = app.MainApp.dataStruct(i).kinAssoData(:, 1);
                assoY = app.MainApp.dataStruct(i).kinAssoData(:, 2);
                rawData(i).Association.X = assoX - assoX(1) + 1;
                rawData(i).Association.Y = assoY - assoY(1);
                
                dissoX = app.MainApp.dataStruct(i).kinDissoData(:, 1);
                rawData(i).Dissociation.Y = app.MainApp.dataStruct(i).kinDissoData(:, 2);
                rawData(i).Dissociation.X = dissoX - dissoX(1) + 1;

                rawData(i).Concentration = numCon(i, 1);
                rawData(i).DesName = desName(i, 1);

                fitProp(i).R0Init = rawData(i).Dissociation.Y(1);
                
                RmaxIdx = strcmp(app.(strcat(app.RmaxDropDown.Value, 'Table')).RowName, 'Rmax');  
                
                if strcmp(app.RmaxDropDown.Value, 'Global')                    
                    fitProp(i).RmaxInit = app.(strcat(app.RmaxDropDown.Value, 'Table')).Data{RmaxIdx, 1};
                else                    
                    fitProp(i).RmaxInit = app.(strcat(app.RmaxDropDown.Value, 'Table')).Data{RmaxIdx, i};
                end
                
                KonIdx = strcmp(app.(strcat(app.KonDropDown.Value, 'Table')).RowName, 'Kon');                
                
                if strcmp(app.KonDropDown.Value, 'Global')                    
                    fitProp(i).KonInit = app.(strcat(app.KonDropDown.Value, 'Table')).Data{KonIdx, 1};                    
                else                    
                    fitProp(i).KonInit = app.(strcat(app.KonDropDown.Value, 'Table')).Data{KonIdx, i};
                end
                
                KoffIdx = strcmp(app.(strcat(app.KoffDropDown.Value, 'Table')).RowName, 'Koff');                
                
                if strcmp(app.KoffDropDown.Value, 'Global')
                    fitProp(i).KoffInit = app.(strcat(app.KoffDropDown.Value, 'Table')).Data{KoffIdx, 1};
                else                    
                    fitProp(i).KoffInit = app.(strcat(app.KoffDropDown.Value, 'Table')).Data{KoffIdx, i};
                end
                
                BIIdx = strcmp(app.(strcat(app.BIDropDown.Value, 'Table')).RowName, 'BI');     
                
                if strcmp(app.BIDropDown.Value, 'Global')
                    fitProp(i).BIInit = app.(strcat(app.BIDropDown.Value, 'Table')).Data{BIIdx, 1};
                else                    
                    fitProp(i).BIInit = app.(strcat(app.BIDropDown.Value, 'Table')).Data{BIIdx, i};
                end
            
            end
            
            [resultMat, fitCurve] = OptFitCurve(app, fitProp);
            FittingResult(resultMat, fitCurve, rawData, app)
            
%             app.ResultTable.Data = resultMat;
%             app.ResultTable.RowName = app.MainApp.UITable.Data.Var2;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GlobalTab = uitab(app.TabGroup);
            app.GlobalTab.Title = 'Global';
            app.GlobalTable = uitable(app.GlobalTab);
            app.GlobalTable.ColumnName = '';
            app.GlobalTable.RowName = {};
            app.LocalTab = uitab(app.TabGroup);
            app.LocalTab.Title = 'Local';
            app.LocalTable = uitable(app.LocalTab);
            app.LocalTable.ColumnName = '';
            app.LocalTable.RowName = {};
            app.ConstantTab = uitab(app.TabGroup);
            app.ConstantTab.Title = 'Constant';
            app.ConstantTable = uitable(app.ConstantTab);
            app.ConstantTable.ColumnName = '';
            app.ConstantTable.RowName = {};
            app.ScopePanel = uipanel(app.UIFigure);
            app.ScopePanel.Title = 'Scope';
            app.RmaxLabel = uilabel(app.ScopePanel);
            app.RmaxLabel.Text = 'Rmax :';
            app.RmaxDropDown = uidropdown(app.ScopePanel);
            app.RmaxDropDown.Items = {'Global', 'Local', 'Constant'};
            app.RmaxDropDown.ValueChangedFcn = createCallbackFcn(app, @RmaxDropDownValueChanged, true);
            app.RmaxDropDown.Value = 'Local';
            app.KonLabel = uilabel(app.ScopePanel);
            app.KonLabel.Text = 'Kon :';
            app.KonDropDown = uidropdown(app.ScopePanel);
            app.KonDropDown.Items = {'Global', 'Local', 'Constant'};
            app.KonDropDown.ValueChangedFcn = createCallbackFcn(app, @KonDropDownValueChanged, true);
            app.KonDropDown.Value = 'Global';
            app.KoffLabel = uilabel(app.ScopePanel);
            app.KoffLabel.Text = 'Koff :';
            app.KoffDropDown = uidropdown(app.ScopePanel);
            app.KoffDropDown.Items = {'Global', 'Local', 'Constant'};
            app.KoffDropDown.ValueChangedFcn = createCallbackFcn(app, @KoffDropDownValueChanged, true);
            app.KoffDropDown.Value = 'Global';
            app.StartFittingButton = uibutton(app.UIFigure, 'push');
            app.StartFittingButton.ButtonPushedFcn = createCallbackFcn(app, @StartFittingButtonPushed, true);
            app.StartFittingButton.Text = 'Start Fitting';
            app.DefaultSetButton = uibutton(app.UIFigure, 'push');
            app.DefaultSetButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultSetButtonPushed, true);
            app.DefaultSetButton.Text = 'Default Set';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.Text = 'Cancel';
            app.MassTransferktCheckBox = uicheckbox(app.UIFigure);
            app.MassTransferktCheckBox.Text = 'Mass Transfer (kt)';
            app.BulkyIndexBICheckBox = uicheckbox(app.UIFigure);
            app.BulkyIndexBICheckBox.Text = 'Bulky Index (BI)';
            app.DriftCheckBox = uicheckbox(app.UIFigure);
            app.DriftCheckBox.Text = 'Drift';
            app.BIDropDownLabel = uilabel(app.UIFigure);
            app.BIDropDownLabel.Text = 'BI :';
            app.BIDropDown = uidropdown(app.UIFigure);
            app.BIDropDown.Items = {'Global', 'Local', 'Constant'};
            app.BIDropDown.ValueChangedFcn = createCallbackFcn(app, @BIDropDownValueChanged, true);
            app.BIDropDown.Value = 'Global';
            app.DriftDropDownLabel = uilabel(app.UIFigure);
            app.DriftDropDownLabel.Text = 'Drift :';
            app.DriftDropDown = uidropdown(app.UIFigure);
            app.DriftDropDown.Items = {'Global', 'Local', 'Constant'};
            app.DriftDropDown.Value = 'Global';
            app.KtDropDownLabel = uilabel(app.UIFigure);
            app.KtDropDownLabel.Text = 'Kt :';
            app.KtDropDown = uidropdown(app.UIFigure);
            app.KtDropDown.Items = {'Global', 'Local', 'Constant'};
            app.KtDropDown.Value = 'Global';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FittingSet(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end