classdef UI_iMScreening_StartUp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StartUpUIFigure   matlab.ui.Figure
        NextButton        matlab.ui.control.Button
        Label_3           matlab.ui.control.Label
        LogFileButton     matlab.ui.control.Button
        Label_2           matlab.ui.control.Label
        RefDataButton     matlab.ui.control.Button
        Label             matlab.ui.control.Label
        TargetDataButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        
    end
    
    properties (Access = public)
        TargetData % Target Curve data (ch1)
        ReferenceData % Reference Curve data (ch2)
        LogData % Auto sampler log data (from iMSam)
        CurrentPath = pwd; % Currently opened directory
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initial Window position (Top-Left)
            scrPos = get(0, 'ScreenSize');
            defFigPos = app.StartUpUIFigure.Position;
            topMargin = 60;
            leftMargin = 10;
            
            app.StartUpUIFigure.Position(1) = leftMargin;
            app.StartUpUIFigure.Position(2) = scrPos(4) - defFigPos(4) - topMargin; 
        end

        % Button pushed function: LogFileButton, RefDataButton, 
        % TargetDataButton
        function TargetDataButtonPushed(app, event)
            [file, path] = uigetfile('*.txt', 'Please select a txt file.', app.CurrentPath);
            
            if isequal(file, 0)
                return
            end
            
            tmpData = importdata(fullfile(path, file));
            
            if strcmp(event.Source.Text, 'Target Data')
                app.TargetData = tmpData;
                app.Label.Text = fullfile(path, file);
            elseif strcmp(event.Source.Text, 'Ref. Data')
                app.ReferenceData = tmpData;
                app.Label_2.Text = fullfile(path, file);
            elseif strcmp(event.Source.Text, 'Log File')
                app.LogData = tmpData;
                app.Label_3.Text = fullfile(path, file);
            end
            
            app.CurrentPath = path;
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if isempty(app.Label.Text) || isempty(app.Label.Text) || isempty(app.Label.Text)
                return                
            end
            
            UI_iMScreening_DataProcessing(app)
        end

        % Close request function: StartUpUIFigure
        function StartUpUIFigureCloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StartUpUIFigure = uifigure('Visible', 'off');
            app.StartUpUIFigure.Name = 'StartUp';
            app.StartUpUIFigure.CloseRequestFcn = createCallbackFcn(app, @StartUpUIFigureCloseRequest, true);
            app.TargetDataButton = uibutton(app.StartUpUIFigure, 'push');
            app.TargetDataButton.ButtonPushedFcn = createCallbackFcn(app, @TargetDataButtonPushed, true);
            app.TargetDataButton.Text = 'Target Data';
            app.Label = uilabel(app.StartUpUIFigure);
            app.Label.Text = '';
            app.RefDataButton = uibutton(app.StartUpUIFigure, 'push');
            app.RefDataButton.ButtonPushedFcn = createCallbackFcn(app, @TargetDataButtonPushed, true);
            app.RefDataButton.Text = 'Ref. Data';
            app.Label_2 = uilabel(app.StartUpUIFigure);
            app.Label_2.Text = '';
            app.LogFileButton = uibutton(app.StartUpUIFigure, 'push');
            app.LogFileButton.ButtonPushedFcn = createCallbackFcn(app, @TargetDataButtonPushed, true);
            app.LogFileButton.Text = 'Log File';
            app.Label_3 = uilabel(app.StartUpUIFigure);
            app.Label_3.Text = '';
            app.NextButton = uibutton(app.StartUpUIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            % Show the figure after all components are created
            app.StartUpUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI_iMScreening_StartUp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.StartUpUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.StartUpUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StartUpUIFigure)
        end
    end
end