classdef UI_iMerge_DisplayResult < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        SettingPanel              matlab.ui.container.Panel
        ThresholdPanel            matlab.ui.container.Panel
        LineColorLabel            matlab.ui.control.Label
        ThresholdLineColorButton  matlab.ui.control.Button
        RUEditField               matlab.ui.control.NumericEditField
        RUEditFieldLabel          matlab.ui.control.Label
        ApplyButton               matlab.ui.control.Button
        MarkerPanel               matlab.ui.container.Panel
        MarkerSizeSpinner         matlab.ui.control.Spinner
        MarkerSizeLabel           matlab.ui.control.Label
        ThresholdColorButton      matlab.ui.control.Button
        ThresholdLabel            matlab.ui.control.Label
        NegativeColorButton       matlab.ui.control.Button
        NegativeLabel             matlab.ui.control.Label
        PositiveColorButton       matlab.ui.control.Button
        PositiveLabel             matlab.ui.control.Label
        MarkerColorButton         matlab.ui.control.Button
        MarkerLabel               matlab.ui.control.Label
        ExportDataButton          matlab.ui.control.Button
        UITable                   matlab.ui.control.Table
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        scatterPlot; % Scattered plot on UIAxes
        MainApp; % Main Application (AnalysisData.mlapp)
        thresholdLine;
        controlKeyPress; %
        selectedCells;
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.MainApp = MainApp;
            
            xData = app.MainApp.UITable.Data.Order;
            yData = app.MainApp.UITable.Data.Average;
            
            cla(app.UIAxes)
            app.scatterPlot = scatter(app.UIAxes, xData, yData, 'filled', 'SizeData', 5);
            app.RUEditField.Value = mean(yData);
            app.thresholdLine = yline(app.UIAxes, mean(yData), 'LineWidth', 2, 'LineStyle', ':');
            app.UITable.ColumnName = {'Order', 'Index', 'ID', 'Average', 'Type', 'Remark'};
            
            ApplyButtonPushed(app, [])
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            % Set Marker size
            app.scatterPlot.SizeData = app.MarkerSizeSpinner.Value;
            
            % Set threshold line position and color
            app.thresholdLine.Color = app.ThresholdLineColorButton.BackgroundColor;
            app.thresholdLine.LineWidth = 2;
            app.thresholdLine.Value = app.RUEditField.Value;
            
            % Set marker colors
            app.scatterPlot.MarkerFaceColor = 'flat';            
            app.scatterPlot.CData = ones(size(app.scatterPlot.YData, 2), 3).*app.MarkerColorButton.BackgroundColor;
            
            for i = 1:size(app.scatterPlot.YData, 2)
                if app.scatterPlot.YData(i) >= app.thresholdLine.Value
                    app.scatterPlot.CData(i, :) = app.ThresholdColorButton.BackgroundColor;
                end
                if strcmp(app.MainApp.UITable.Data.Type{i}, 'Positive')
                    app.scatterPlot.CData(i, :) = app.PositiveColorButton.BackgroundColor;
                elseif strcmp(app.MainApp.UITable.Data.Type{i}, 'Negative')
                    app.scatterPlot.CData(i, :) = app.NegativeColorButton.BackgroundColor;
                end
            end
            
            % Scatter Plot DataTip
            app.scatterPlot.DataTipTemplate.DataTipRows(1).Label = 'Idx';
            app.scatterPlot.DataTipTemplate.DataTipRows(2).Label = 'Avg';
            app.scatterPlot.DataTipTemplate.DataTipRows(3).Label = 'Type';
            app.scatterPlot.DataTipTemplate.DataTipRows(1).Value = app.MainApp.UITable.Data.Index;
            app.scatterPlot.DataTipTemplate.DataTipRows(2).Value = round(app.MainApp.UITable.Data.Average*100)/100;
            app.scatterPlot.DataTipTemplate.DataTipRows(3).Value = app.MainApp.UITable.Data.Type;
            
            % Display table data            
            Order = app.MainApp.UITable.Data.Order(app.MainApp.UITable.Data.Average >= app.thresholdLine.Value);
            Index = app.MainApp.UITable.Data.Index(app.MainApp.UITable.Data.Average >= app.thresholdLine.Value);
            Average = app.MainApp.UITable.Data.Average(app.MainApp.UITable.Data.Average >= app.thresholdLine.Value);
            Type = app.MainApp.UITable.Data.Type(app.MainApp.UITable.Data.Average >= app.thresholdLine.Value); 
            ID = cell(size(Order)); ID(:, :) = {''};
            Remark = cell(size(Order)); Remark(:, :) = {''};
            tableData = table(Order, Index, ID, Average, Type, Remark);
            app.UITable.Data = tableData;
            app.UITable.ColumnEditable = [false, false, true, false, false, true];
%             app.UITable.ColumnFormat = {'numeric', 'numeric', 'char', 'numeric', 'char', 'char'};
        end

        % Button pushed function: MarkerColorButton, 
        % NegativeColorButton, PositiveColorButton, 
        % ThresholdColorButton, ThresholdLineColorButton
        function MarkerColorButtonPushed(app, event)
            setColor = uisetcolor(event.Source.BackgroundColor, 'Select a color');
            if isequal(setColor, 0); return; end
            event.Source.BackgroundColor = setColor;
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            sizeData = ones(size(app.scatterPlot.YData)) * app.MarkerSizeSpinner.Value;
            for i = 1:size(event.Indices, 1)
                selectedPoint = app.UITable.Data.Order(event.Indices(i));
                sizeData(selectedPoint) = app.MarkerSizeSpinner.Value * 5;
            end
            app.scatterPlot.SizeData = sizeData;
            
            app.selectedCells = event.Indices;
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
            sizeData = ones(size(app.scatterPlot.YData)) * app.MarkerSizeSpinner.Value;
            app.scatterPlot.SizeData = sizeData;
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            selPath = uigetdir(app.MainApp.ResultFilePath, 'Save folder');
            
            if isequal(selPath, 0); return; end
            formatOut = 'yymmddHHMMSS';
            dateStr = datestr(now, formatOut);
            extension = 'txt';
            
            fileName = strcat('Processed_curve', dateStr, '.', extension);
            fullFile = fullfile(selPath, fileName);
            writetable(app.UITable.Data, fullFile, 'Delimiter', 'tab')
        end

        % Key press function: UITable
        function UITableKeyPress(app, event)
            if strcmp(event.Key, 'control'); app.controlKeyPress = 1; end            
            if strcmp(event.Key, 'c')
                if app.controlKeyPress == 1
                    str = '';
                    cellTable = table2cell(app.UITable.Data);
                    strTable = cellfun(@num2str, cellTable, 'UniformOutput', false);                    
                    firstColumn = app.selectedCells(1, 1);
                    lastColumn = app.selectedCells(end, 1);
                    firstRow = app.selectedCells(1, 2);
                    lastRow = app.selectedCells(end, 2);                    
                    targetData = strTable(firstColumn:lastColumn, firstRow:lastRow);                    
                    tableCol = size(targetData, 1);
                    tableRow = size(targetData, 2);
                    for col = 1:tableCol
                        for row = 1:tableRow
                            if row == tableRow
                                str = sprintf('%s%s', str, targetData{col, row});
                            else
                                str = sprintf('%s%s\t', str, targetData{col, row});
                            end
                        end
                        str = sprintf('%s\n', str);
                    end                    
                    clipboard('copy', str)
                end
            end
        end

        % Key release function: UITable
        function UITableKeyRelease(app, event)
%             if strcmp(event.Key, 'control'); app.controlKeyPress = 0; end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.MainApp.UIFigure.WindowState = 'normal';
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Order')
            ylabel(app.UIAxes, 'Response (RU)')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.KeyPressFcn = createCallbackFcn(app, @UITableKeyPress, true);
            app.UITable.KeyReleaseFcn = createCallbackFcn(app, @UITableKeyRelease, true);
            app.ExportDataButton = uibutton(app.UIFigure, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.SettingPanel = uipanel(app.UIFigure);
            app.SettingPanel.Title = 'Setting';
            app.MarkerPanel = uipanel(app.SettingPanel);
            app.MarkerPanel.Title = 'Marker';
            app.MarkerLabel = uilabel(app.MarkerPanel);
            app.MarkerLabel.Text = 'Marker:';
            app.MarkerColorButton = uibutton(app.MarkerPanel, 'push');
            app.MarkerColorButton.ButtonPushedFcn = createCallbackFcn(app, @MarkerColorButtonPushed, true);
            app.MarkerColorButton.Text = '';
            app.PositiveLabel = uilabel(app.MarkerPanel);
            app.PositiveLabel.Text = 'Positive:';
            app.PositiveColorButton = uibutton(app.MarkerPanel, 'push');
            app.PositiveColorButton.ButtonPushedFcn = createCallbackFcn(app, @MarkerColorButtonPushed, true);
            app.PositiveColorButton.Text = '';
            app.NegativeLabel = uilabel(app.MarkerPanel);
            app.NegativeLabel.Text = 'Negative:';
            app.NegativeColorButton = uibutton(app.MarkerPanel, 'push');
            app.NegativeColorButton.ButtonPushedFcn = createCallbackFcn(app, @MarkerColorButtonPushed, true);
            app.NegativeColorButton.Text = '';
            app.ThresholdLabel = uilabel(app.MarkerPanel);
            app.ThresholdLabel.Text = '> Threshold:';
            app.ThresholdColorButton = uibutton(app.MarkerPanel, 'push');
            app.ThresholdColorButton.ButtonPushedFcn = createCallbackFcn(app, @MarkerColorButtonPushed, true);
            app.ThresholdColorButton.Text = '';
            app.MarkerSizeLabel = uilabel(app.MarkerPanel);
            app.MarkerSizeLabel.Text = 'Marker Size:';
            app.MarkerSizeSpinner = uispinner(app.MarkerPanel);
            app.MarkerSizeSpinner.Value = 10;
            app.ApplyButton = uibutton(app.SettingPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.ThresholdPanel = uipanel(app.SettingPanel);
            app.ThresholdPanel.Title = 'Threshold';
            app.RUEditFieldLabel = uilabel(app.ThresholdPanel);
            app.RUEditFieldLabel.Text = 'RU';
            app.RUEditField = uieditfield(app.ThresholdPanel, 'numeric');
            app.ThresholdLineColorButton = uibutton(app.ThresholdPanel, 'push');
            app.ThresholdLineColorButton.ButtonPushedFcn = createCallbackFcn(app, @MarkerColorButtonPushed, true);
            app.ThresholdLineColorButton.Text = '';
            app.LineColorLabel = uilabel(app.ThresholdPanel);
            app.LineColorLabel.Text = 'Line Color:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI_iMerge_DisplayResult(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end