classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DropletCounterUIFigure    matlab.ui.Figure
        FileMenu                  matlab.ui.container.Menu
        LoadanImageMenu           matlab.ui.container.Menu
        SaveResultMenu            matlab.ui.container.Menu
        CloseMenu                 matlab.ui.container.Menu
        RunMenu                   matlab.ui.container.Menu
        CountDropletMenu          matlab.ui.container.Menu
        ShowUnknownsMenu          matlab.ui.container.Menu
        ImageMenu                 matlab.ui.container.Menu
        ROISettingMenu            matlab.ui.container.Menu
        ConfigurationMenu         matlab.ui.container.Menu
        MeasureMenu               matlab.ui.container.Menu
        CircleMenu                matlab.ui.container.Menu
        LineMenu                  matlab.ui.container.Menu
        RectangleMenu             matlab.ui.container.Menu
        HelpMenu                  matlab.ui.container.Menu
        AboutMenu                 matlab.ui.container.Menu
        UserManualMenu            matlab.ui.container.Menu
        GridLayout                matlab.ui.container.GridLayout
        ColorSettingPanel         matlab.ui.container.Panel
        Label_9                   matlab.ui.control.Label
        Label_10                  matlab.ui.control.Label
        TotalLabel                matlab.ui.control.Label
        UnknownLabel              matlab.ui.control.Label
        ShowUnknownsButton        matlab.ui.control.Button
        UITable                   matlab.ui.control.Table
        Image                     matlab.ui.control.Image
        SaveResultButton          matlab.ui.control.Button
        Panel_2                   matlab.ui.container.Panel
        Label_2                   matlab.ui.control.Label
        Label_3                   matlab.ui.control.Label
        Label_4                   matlab.ui.control.Label
        Label_5                   matlab.ui.control.Label
        Label_6                   matlab.ui.control.Label
        Label_7                   matlab.ui.control.Label
        Label_8                   matlab.ui.control.Label
        Image_2                   matlab.ui.control.Image
        Image_3                   matlab.ui.control.Image
        Image_4                   matlab.ui.control.Image
        Image_5                   matlab.ui.control.Image
        Image_6                   matlab.ui.control.Image
        Image_7                   matlab.ui.control.Image
        Image_8                   matlab.ui.control.Image
        CheckBox_2                matlab.ui.control.CheckBox
        CheckBox_3                matlab.ui.control.CheckBox
        CheckBox_4                matlab.ui.control.CheckBox
        CheckBox_5                matlab.ui.control.CheckBox
        CheckBox_6                matlab.ui.control.CheckBox
        CheckBox_7                matlab.ui.control.CheckBox
        CheckBox_8                matlab.ui.control.CheckBox
        Image_1                   matlab.ui.control.Image
        Label_1                   matlab.ui.control.Label
        CheckBox_1                matlab.ui.control.CheckBox
        PreviewImagePanel         matlab.ui.container.Panel
        GridLayout4               matlab.ui.container.GridLayout
        ROICheckBox               matlab.ui.control.CheckBox
        ClearShapesButton         matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
        SaveImageButton           matlab.ui.control.Button
        ToClipboardButton         matlab.ui.control.Button
        Panel_3                   matlab.ui.container.Panel
        GridLayout5               matlab.ui.container.GridLayout
        LoadButton                matlab.ui.control.Button
        DetectionPropertiesPanel  matlab.ui.container.Panel
        MinEditFieldLabel         matlab.ui.control.Label
        MinEditField              matlab.ui.control.NumericEditField
        MaxEditFieldLabel         matlab.ui.control.Label
        MaxEditField              matlab.ui.control.NumericEditField
        RadiusPixelsLabel         matlab.ui.control.Label
        SenEditField              matlab.ui.control.NumericEditField
        SensitivityLabel          matlab.ui.control.Label
        CountButton               matlab.ui.control.Button
    end

    
    properties (Access = private)        
        SpoidClickedFlag = 0;
    end
    
    properties (Access = public)
        ConfigApp
        TgtImg = [];
        TgtImgOrg = [];
        img = InitColor;  
        ROIRect = [];
        ROIPosition = [];
        ROIAction = [];
        CropImg = [];
        CntRes = [];
        BrightnessInit = 100;
        ContrastInit = 100;
        Brightness = [];
        Contrast = [];
        Shape = [];
        ShapeAction = [];
        MeasureText = [];
        ctrlPress = 0;
        ResultImg = [];
        UnknownImg = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            for i = 1:8
                
                Image_n = sprintf('Image_%d', i);                
                app.(Image_n).ImageSource = app.img.Background{i};
                
                app.img.Selected = 1;
                LabelSelected(app)
                app.UITable.Data = app.img.Color{app.img.Selected};
                
                SetButtonStatus(app, 'Initial')
                
                posMtr = get(0, 'Screensize');
                                
                app.DropletCounterUIFigure.Position(1) = (posMtr(3)-app.DropletCounterUIFigure.Position(3))/2;
                app.DropletCounterUIFigure.Position(2) = (posMtr(4)-app.DropletCounterUIFigure.Position(4))/2;
                
            end
        end

        % Callback function: CheckBox_1, CheckBox_2, CheckBox_3, 
        % CheckBox_4, CheckBox_5, CheckBox_6, CheckBox_7, 
        % CheckBox_8, Image_1, Image_2, Image_3, Image_4, Image_5, 
        % Image_6, Image_7, Image_8
        function ImageClicked(app, event)
            app.img.Selected = str2double(event.Source.Tag(6:end));  
            LabelSelected(app)
            app.UITable.Data = app.img.Color{app.img.Selected};        
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            app.img.Color{app.img.Selected}(indices(1), indices(2)) = newData;
            app.img = MakeBackground(app.img);
            
            Image_n = sprintf('Image_%d', app.img.Selected);
            app.(Image_n).ImageSource = app.img.Background{app.img.Selected};
        end

        % Callback function: LoadButton, LoadanImageMenu
        function LoadButtonPushed(app, event)
            [FileName, PathName] = uigetfile({'*.png;*.jpg; *.tif; *.bmp', 'Image Files'}, 'Select a file');
            FullName = fullfile(PathName, FileName);
            
            if FileName ~= 0
                
%                 cla(app.UIAxes)
                LoadImage(app, FullName)
                
            end
        end

        % Image clicked function: Image
        function SpoidClicked(app, event)
            if app.img.Selected == 0
                
                return
                
            end
            
            PointObj = drawpoint(app.UIAxes);
            
            if isempty(PointObj.Position)
                
                return
                
            end
            
            SpoidPoint = flip(round(PointObj.Position));
            
            if SpoidPoint(1) < size(app.TgtImg, 1) && SpoidPoint(2) < size(app.TgtImg, 2)
                
                PointColor = uint8(app.TgtImg(SpoidPoint(1), SpoidPoint(2), :)/2^8); % 16 bit to 8 bit
            
                app.img.Color{app.img.Selected}(:, 1) = PointColor;
                app.UITable.Data = app.img.Color{app.img.Selected};
                
                app.img = MakeBackground(app.img);
                Image_n = sprintf('Image_%d', app.img.Selected);        
                app.(Image_n).ImageSource = app.img.Background{app.img.Selected};   
                
                LabelSelected(app)
                
            end
            
            delete(PointObj)
            
        end

        % Value changed function: ROICheckBox
        function ROICheckBoxValueChanged(app, event)
            MakeROIRectangle(app)
        end

        % Callback function: CountButton, CountDropletMenu
        function CountButtonPushed(app, event)
            CountDroplet(app)
        end

        % Callback function: ShowUnknownsButton, ShowUnknownsMenu
        function ShowUnknownsButtonPushed(app, event)
            CheckUnknown(app)
        end

        % Menu selected function: ConfigurationMenu
        function ConfigurationMenuSelected(app, event)
            RunConfigMenu(app)
        end

        % Callback function: CloseMenu, DropletCounterUIFigure
        function DropletCounterUIFigureCloseRequest(app, event)
            delete(app.ConfigApp)
            delete(app)
        end

        % Menu selected function: ROISettingMenu
        function ROISettingMenuSelected(app, event)
            if app.ROICheckBox.Value == 1
                
                return
                
            end
            
            app.ROICheckBox.Value = 1;            
            ROICheckBoxValueChanged(app, event)
        end

        % Callback function: SaveResultButton, SaveResultMenu
        function SaveResultButtonPushed(app, event)
            SaveData(app)
        end

        % Menu selected function: CircleMenu, LineMenu, 
        % RectangleMenu
        function MeasureMenuSelected(app, event)
            MeasureShape(app, event)
        end

        % Button pushed function: ClearShapesButton
        function ClearShapesButtonPushed(app, event)
            delete(findobj(app.UIAxes, 'Type', 'Text'))
            delete(app.Shape)
            
            SetButtonStatus(app, 'MeasureOff')
        end

        % Key press function: DropletCounterUIFigure
        function DropletCounterUIFigureKeyPress(app, event)
            key = event.Key;
            
            if strcmp(key, 'control')
                
                app.ctrlPress = 1;
                
            end
            
            if app.ctrlPress == 1
                
                if strcmp(key, 's')
                    
                    if app.SaveResultMenu.Enable == 1
                
                        SaveResultButtonPushed(app, event)
                        
                    end
                
                elseif strcmp(key, 'l')
                    
                    if app.LoadanImageMenu.Enable == 1
                
                        LoadButtonPushed(app, event)
                        
                    end
                    
                elseif strcmp(key, 'u')
                    
                    if app.ShowUnknownsMenu.Enable == 1
                        
                        ShowUnknownsButtonPushed(app, event)
                        
                    end
                    
                elseif strcmp(key, 'r')
                    
                    if app.ROISettingMenu.Enable == 1
                    
                        ROISettingMenuSelected(app, event)
                        
                    end
                    
                elseif strcmp(key, 'f')
                    
                    if app.ConfigurationMenu.Enable == 1
                    
                        ConfigurationMenuSelected(app, event)
                        
                    end
                
                end
                
            else
                
                if strcmp(key, 'f1')
                    
                    disp('help')
                    
                elseif strcmp(key, 'f5')
                
                    if app.CountDropletMenu.Enable == 1
                    
                        CountButtonPushed(app, event)
                        
                    end
                    
                end
                
            end
            
        end

        % Key release function: DropletCounterUIFigure
        function DropletCounterUIFigureKeyRelease(app, event)
            key = event.Key;
            
            if strcmp(key, 'control')
                
                app.ctrlPress = 0;
                
            end
            
        end

        % Size changed function: DropletCounterUIFigure
        function DropletCounterUIFigureSizeChanged(app, event)
            sizeMin = [1011 750];
            if app.DropletCounterUIFigure.Position(3) < sizeMin(1)
                
                app.DropletCounterUIFigure.Position(3) = sizeMin(1);
            
            end
            
            if app.DropletCounterUIFigure.Position(4) < sizeMin(2)
                
                app.DropletCounterUIFigure.Position(4) = sizeMin(2);
            
            end
        end

        % Button pushed function: SaveImageButton, ToClipboardButton
        function SaveImageButtonPushed(app, event)
           SaveImage(app, event.Source.Tag)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DropletCounterUIFigure = uifigure('Visible', 'off');
            app.DropletCounterUIFigure.Name = 'Droplet Counter';
            app.DropletCounterUIFigure.CloseRequestFcn = createCallbackFcn(app, @DropletCounterUIFigureCloseRequest, true);
            app.DropletCounterUIFigure.SizeChangedFcn = createCallbackFcn(app, @DropletCounterUIFigureSizeChanged, true);
            app.DropletCounterUIFigure.KeyPressFcn = createCallbackFcn(app, @DropletCounterUIFigureKeyPress, true);
            app.DropletCounterUIFigure.KeyReleaseFcn = createCallbackFcn(app, @DropletCounterUIFigureKeyRelease, true);
            app.FileMenu = uimenu(app.DropletCounterUIFigure);
            app.FileMenu.Text = 'File';
            app.LoadanImageMenu = uimenu(app.FileMenu);
            app.LoadanImageMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadanImageMenu.Text = 'Load an Image (Ctrl + L)';
            app.SaveResultMenu = uimenu(app.FileMenu);
            app.SaveResultMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveResultButtonPushed, true);
            app.SaveResultMenu.Text = 'Save Result (Ctrl + S)';
            app.CloseMenu = uimenu(app.FileMenu);
            app.CloseMenu.MenuSelectedFcn = createCallbackFcn(app, @DropletCounterUIFigureCloseRequest, true);
            app.CloseMenu.Text = 'Close';
            app.RunMenu = uimenu(app.DropletCounterUIFigure);
            app.RunMenu.Text = 'Run';
            app.CountDropletMenu = uimenu(app.RunMenu);
            app.CountDropletMenu.MenuSelectedFcn = createCallbackFcn(app, @CountButtonPushed, true);
            app.CountDropletMenu.Text = 'Count Droplet (F5)';
            app.ShowUnknownsMenu = uimenu(app.RunMenu);
            app.ShowUnknownsMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowUnknownsButtonPushed, true);
            app.ShowUnknownsMenu.Text = 'Show Unknowns (Ctrl + U)';
            app.ImageMenu = uimenu(app.DropletCounterUIFigure);
            app.ImageMenu.Text = 'Image';
            app.ROISettingMenu = uimenu(app.ImageMenu);
            app.ROISettingMenu.MenuSelectedFcn = createCallbackFcn(app, @ROISettingMenuSelected, true);
            app.ROISettingMenu.Text = 'ROI Setting (Ctrl + R)';
            app.ConfigurationMenu = uimenu(app.ImageMenu);
            app.ConfigurationMenu.MenuSelectedFcn = createCallbackFcn(app, @ConfigurationMenuSelected, true);
            app.ConfigurationMenu.Text = 'Configuration (Ctrl + F)';
            app.MeasureMenu = uimenu(app.ImageMenu);
            app.MeasureMenu.Text = 'Measure';
            app.CircleMenu = uimenu(app.MeasureMenu);
            app.CircleMenu.MenuSelectedFcn = createCallbackFcn(app, @MeasureMenuSelected, true);
            app.CircleMenu.Text = 'Circle';
            app.CircleMenu.Tag = 'MeasureCircle';
            app.LineMenu = uimenu(app.MeasureMenu);
            app.LineMenu.MenuSelectedFcn = createCallbackFcn(app, @MeasureMenuSelected, true);
            app.LineMenu.Text = 'Line';
            app.LineMenu.Tag = 'MeasureLine';
            app.RectangleMenu = uimenu(app.MeasureMenu);
            app.RectangleMenu.MenuSelectedFcn = createCallbackFcn(app, @MeasureMenuSelected, true);
            app.RectangleMenu.Text = 'Rectangle';
            app.RectangleMenu.Tag = 'MeasureRectangle';
            app.HelpMenu = uimenu(app.DropletCounterUIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.Text = 'About';
            app.UserManualMenu = uimenu(app.HelpMenu);
            app.UserManualMenu.Text = 'User Manual (F1)';
            app.GridLayout = uigridlayout(app.DropletCounterUIFigure);
            app.ColorSettingPanel = uipanel(app.GridLayout);
            app.ColorSettingPanel.Title = 'Color Setting';
            app.Label_9 = uilabel(app.ColorSettingPanel);
            app.Label_9.Text = '0';
            app.Label_10 = uilabel(app.ColorSettingPanel);
            app.Label_10.Text = '0';
            app.TotalLabel = uilabel(app.ColorSettingPanel);
            app.TotalLabel.Text = 'Total';
            app.UnknownLabel = uilabel(app.ColorSettingPanel);
            app.UnknownLabel.Text = 'Unknown';
            app.ShowUnknownsButton = uibutton(app.ColorSettingPanel, 'push');
            app.ShowUnknownsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowUnknownsButtonPushed, true);
            app.ShowUnknownsButton.Text = 'Unknown';
            app.UITable = uitable(app.ColorSettingPanel);
            app.UITable.ColumnName = {'Value'; 'Range'};
            app.UITable.RowName = {'R'; 'G'; 'B'};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.Image = uiimage(app.ColorSettingPanel);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @SpoidClicked, true);
            app.Image.ImageSource = 'spoid.png';
            app.SaveResultButton = uibutton(app.ColorSettingPanel, 'push');
            app.SaveResultButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultButtonPushed, true);
            app.SaveResultButton.Text = {'Save Result'; '(.xls file)'};
            app.Panel_2 = uipanel(app.ColorSettingPanel);
            app.Label_2 = uilabel(app.Panel_2);
            app.Label_2.Text = '0';
            app.Label_3 = uilabel(app.Panel_2);
            app.Label_3.Text = '0';
            app.Label_4 = uilabel(app.Panel_2);
            app.Label_4.Text = '0';
            app.Label_5 = uilabel(app.Panel_2);
            app.Label_5.Text = '0';
            app.Label_6 = uilabel(app.Panel_2);
            app.Label_6.Text = '0';
            app.Label_7 = uilabel(app.Panel_2);
            app.Label_7.Text = '0';
            app.Label_8 = uilabel(app.Panel_2);
            app.Label_8.Text = '0';
            app.Image_2 = uiimage(app.Panel_2);
            app.Image_2.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image_2.Tag = 'Image2';
            app.Image_3 = uiimage(app.Panel_2);
            app.Image_3.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image_3.Tag = 'Image3';
            app.Image_4 = uiimage(app.Panel_2);
            app.Image_4.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image_4.Tag = 'Image4';
            app.Image_5 = uiimage(app.Panel_2);
            app.Image_5.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image_5.Tag = 'Image5';
            app.Image_6 = uiimage(app.Panel_2);
            app.Image_6.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image_6.Tag = 'Image6';
            app.Image_7 = uiimage(app.Panel_2);
            app.Image_7.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image_7.Tag = 'Image7';
            app.Image_8 = uiimage(app.Panel_2);
            app.Image_8.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image_8.Tag = 'Image8';
            app.CheckBox_2 = uicheckbox(app.Panel_2);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.CheckBox_2.Tag = 'Check2';
            app.CheckBox_2.Text = '';
            app.CheckBox_2.Value = true;
            app.CheckBox_3 = uicheckbox(app.Panel_2);
            app.CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.CheckBox_3.Tag = 'Check3';
            app.CheckBox_3.Text = '';
            app.CheckBox_3.Value = true;
            app.CheckBox_4 = uicheckbox(app.Panel_2);
            app.CheckBox_4.ValueChangedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.CheckBox_4.Tag = 'Check4';
            app.CheckBox_4.Text = '';
            app.CheckBox_4.Value = true;
            app.CheckBox_5 = uicheckbox(app.Panel_2);
            app.CheckBox_5.ValueChangedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.CheckBox_5.Tag = 'Check5';
            app.CheckBox_5.Text = '';
            app.CheckBox_6 = uicheckbox(app.Panel_2);
            app.CheckBox_6.ValueChangedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.CheckBox_6.Tag = 'Check6';
            app.CheckBox_6.Text = '';
            app.CheckBox_7 = uicheckbox(app.Panel_2);
            app.CheckBox_7.ValueChangedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.CheckBox_7.Tag = 'Check7';
            app.CheckBox_7.Text = '';
            app.CheckBox_8 = uicheckbox(app.Panel_2);
            app.CheckBox_8.ValueChangedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.CheckBox_8.Tag = 'Check8';
            app.CheckBox_8.Text = '';
            app.Image_1 = uiimage(app.Panel_2);
            app.Image_1.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image_1.Tag = 'Image1';
            app.Label_1 = uilabel(app.Panel_2);
            app.Label_1.Text = '0';
            app.CheckBox_1 = uicheckbox(app.Panel_2);
            app.CheckBox_1.ValueChangedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.CheckBox_1.Tag = 'Check1';
            app.CheckBox_1.Text = '';
            app.CheckBox_1.Value = true;
            app.PreviewImagePanel = uipanel(app.GridLayout);
            app.PreviewImagePanel.Title = 'Preview Image';
            app.GridLayout4 = uigridlayout(app.PreviewImagePanel);
            app.ROICheckBox = uicheckbox(app.GridLayout4);
            app.ROICheckBox.ValueChangedFcn = createCallbackFcn(app, @ROICheckBoxValueChanged, true);
            app.ROICheckBox.Text = 'ROI';
            app.ClearShapesButton = uibutton(app.GridLayout4, 'push');
            app.ClearShapesButton.ButtonPushedFcn = createCallbackFcn(app, @ClearShapesButtonPushed, true);
            app.ClearShapesButton.Text = 'Clear Shapes';
            app.UIAxes = uiaxes(app.GridLayout4);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.UIAxes.ZTick = [];
            app.UIAxes.ZTickLabel = '';
            app.SaveImageButton = uibutton(app.GridLayout4, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Tag = 'SaveImage';
            app.SaveImageButton.Text = 'Save Image';
            app.ToClipboardButton = uibutton(app.GridLayout4, 'push');
            app.ToClipboardButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.ToClipboardButton.Tag = 'ToClipboard';
            app.ToClipboardButton.Text = 'To Clipboard';
            app.Panel_3 = uipanel(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.Panel_3);
            app.LoadButton = uibutton(app.GridLayout5, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load an Image';
            app.DetectionPropertiesPanel = uipanel(app.GridLayout5);
            app.DetectionPropertiesPanel.Title = 'Detection Properties';
            app.MinEditFieldLabel = uilabel(app.DetectionPropertiesPanel);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.DetectionPropertiesPanel, 'numeric');
            app.MinEditField.Value = 10;
            app.MaxEditFieldLabel = uilabel(app.DetectionPropertiesPanel);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.DetectionPropertiesPanel, 'numeric');
            app.MaxEditField.Value = 20;
            app.RadiusPixelsLabel = uilabel(app.DetectionPropertiesPanel);
            app.RadiusPixelsLabel.Text = {'Radius'; '(Pixels)'};
            app.SenEditField = uieditfield(app.DetectionPropertiesPanel, 'numeric');
            app.SenEditField.Value = 0.95;
            app.SensitivityLabel = uilabel(app.DetectionPropertiesPanel);
            app.SensitivityLabel.Text = 'Sensitivity';
            app.CountButton = uibutton(app.GridLayout5, 'push');
            app.CountButton.ButtonPushedFcn = createCallbackFcn(app, @CountButtonPushed, true);
            app.CountButton.Text = 'Count Droplets (F5)';
            % Show the figure after all components are created
            app.DropletCounterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DropletCounterUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DropletCounterUIFigure)
        end
    end
end